classdef SimulationView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SimulatePlot                  matlab.ui.control.UIAxes
        ResultsLabel                  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        PhaseportraitTab              matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        MMIFT4Tab                     matlab.ui.container.Tab
        MMI_FT4                       matlab.ui.control.UIAxes
        MMIThyroidSTab                matlab.ui.container.Tab
        MMI_TS                        matlab.ui.control.UIAxes
        MMITRAbTab                    matlab.ui.container.Tab
        MMI_TRAB                      matlab.ui.control.UIAxes
        FT4MMITab                     matlab.ui.container.Tab
        FT4_MMI                       matlab.ui.control.UIAxes
        FT4ThyroidSTab                matlab.ui.container.Tab
        FT4_TS                        matlab.ui.control.UIAxes
        FT4TRAbTab                    matlab.ui.container.Tab
        FT4_TRAB                      matlab.ui.control.UIAxes
        ThyroidSMMITab                matlab.ui.container.Tab
        TS_MMI                        matlab.ui.control.UIAxes
        ThyroidSFT4Tab                matlab.ui.container.Tab
        TS_FT4                        matlab.ui.control.UIAxes
        ThyroidSTRAbTab               matlab.ui.container.Tab
        TS_TRAB                       matlab.ui.control.UIAxes
        TRAbMMITab                    matlab.ui.container.Tab
        TRAB_MMI                      matlab.ui.control.UIAxes
        TRAbFT4Tab                    matlab.ui.container.Tab
        TRAB_FT4                      matlab.ui.control.UIAxes
        TRAbThyroidSTab               matlab.ui.container.Tab
        TRAB_TS                       matlab.ui.control.UIAxes
        DetailsTab                    matlab.ui.container.Tab
        EigenResultTable              matlab.ui.control.Table
        InitialvaluesLabel            matlab.ui.control.Label
        TRAbEditFieldLabel            matlab.ui.control.Label
        TRAbEditField                 matlab.ui.control.NumericEditField
        MMIEditFieldLabel             matlab.ui.control.Label
        MMIEditField                  matlab.ui.control.NumericEditField
        FT4EditFieldLabel             matlab.ui.control.Label
        FT4EditField                  matlab.ui.control.NumericEditField
        ThyroidsizeEditFieldLabel     matlab.ui.control.Label
        ThyroidsizeEditField          matlab.ui.control.NumericEditField
        SimulationtimeEditFieldLabel  matlab.ui.control.Label
        SimulationtimeEditField       matlab.ui.control.NumericEditField
        DoseEditFieldLabel            matlab.ui.control.Label
        DoseEditField                 matlab.ui.control.EditField
        DaysEditFieldLabel            matlab.ui.control.Label
        DaysEditField                 matlab.ui.control.EditField
        PrintCopyButton               matlab.ui.control.Button
        FigureButton                  matlab.ui.control.Button
        CompareButton                 matlab.ui.control.Button
    end


    properties (Access = public)
        ISimulationResult
    end

    methods ( Static, Access = public )

        function PlotInit(app)
            
    f = app.UIFigure;
    d = uiprogressdlg(f,'Title','Please Wait',...
        'Message','Loading simulation',...
         'Indeterminate','off');
    d.Value = .01; 
    pause(1)
            

            res = app.ISimulationResult;
            show_simulation(app.SimulatePlot, res.T,res.X, res.ISimulation.Patient.label);
            app.SimulatePlot.XLim = [0 res.ISimulation.SimulationTime];
            res.ISimulation.Dose;   
%             app.SimulatePlot.Title.String = res.ISimulation.Dose + '';

            plabel = res.ISimulation.Patient.label;
            title = plabel;
            
            doses = res.ISimulation.Dose;
            
            n = length(doses.grams);
            
            doseSchema = "\n Dose: %dmg/day for %d days";
            for i = 1:n
               gram = doses.grams(i);
               day = doses.days(i);
               title = title + sprintf(doseSchema,gram, day);
            end
            
            app.SimulatePlot.Title.String = title;
                          % Perform calculations
    % ...
    d.Value = .33; 
    d.Message = 'Loading stability points';
    pause(1)
            
            UIAxes = [app.MMI_FT4 app.MMI_TS app.MMI_TRAB app.FT4_MMI app.FT4_TS app.FT4_TRAB  app.TS_MMI  app.TS_FT4 app.TS_TRAB app.TRAB_MMI app.TRAB_FT4 app.TRAB_TS];
            show_stability(UIAxes, res.X, res.points);
                          % Perform calculations
    % ...
    d.Value = .66; 
    d.Message = 'Loading phase portraits';
    pause(1)

            tdata = table(res.eigen);
            app.EigenResultTable.Data = tdata;
            
            details = res.ISimulation;
            app.SimulationtimeEditField.Value = details.SimulationTime;
%             todo
            app.DoseEditField.Value = mat2str(details.Dose.grams);
            app.DaysEditField.Value = mat2str(details.Dose.days);
            
            app.MMIEditField.Value = details.InitialValues.x;
            app.FT4EditField.Value = details.InitialValues.y;
            app.ThyroidsizeEditField.Value = details.InitialValues.z;
            app.TRAbEditField.Value = details.InitialValues.w;
            
            
    d.Value = 1;
    d.Message = 'Finishing';
    pause(1)
    
    % Close dialog box
    close(d);
        end
    end


    methods (Access = private)

        % Button pushed function: PrintCopyButton
        function print(app, event)

%           loading bar 
            f = app.UIFigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Exporting to png');
            pause(.5)
            
            name = {app.ISimulationResult.ISimulation.Patient.label ...
                'MMI' num2str(app.MMIEditField.Value) ' FT4'  num2str(app.FT4EditField.Value) ...
                ' TS' num2str(app.ThyroidsizeEditField.Value) ' TRAb' num2str(app.TRAbEditField.Value) ...
                'Dose' app.DoseEditField.Value 'Days' app.DaysEditField.Value '.png' };
            named = strjoin(name);
            
            printFigure(app.SimulatePlot, named);
            
            d.Value = 1;
            d.Message = 'Finishing';
            pause(1)

    % Close dialog box
    close(d);
        end

        % Button pushed function: FigureButton
        function figure(app, event)
            %           loading bar 
            f = app.UIFigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Exporting to figure');
            pause(.5)

            showFigure(app.SimulatePlot);
            
            
            d.Value = 1;
            d.Message = 'Finishing';
            pause(1)
        end

        % Button pushed function: CompareButton
        function CompareButtonFnc(app, event)
            
            cp = app.ISimulationResult.CompareData;
            fig = cp.Figure;
%             simResult = app.ISimulationResult;
            
%             
%             cf = CompareFunction;
%             cf.Data = simResult.X(:,2);
%             cf.Label = "temp";
%             
%             cp.FunctionArray = [cp.FunctionArray cf];
%             cp.T = simResult.T;
            
            compareFigure(fig, cp.T, cp.FunctionArray);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SimulatePlot = uiaxes(app.UIFigure);
            title(app.SimulatePlot, 'Title')
            xlabel(app.SimulatePlot, 'X')
            ylabel(app.SimulatePlot, 'Y')
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PhaseportraitTab = uitab(app.TabGroup);
            app.PhaseportraitTab.Title = 'Phase portrait';
            app.TabGroup2 = uitabgroup(app.PhaseportraitTab);
            app.MMIFT4Tab = uitab(app.TabGroup2);
            app.MMIFT4Tab.Title = 'MMI-FT4';
            app.MMI_FT4 = uiaxes(app.MMIFT4Tab);
            title(app.MMI_FT4, 'Title')
            xlabel(app.MMI_FT4, 'X')
            ylabel(app.MMI_FT4, 'Y')
            app.MMIThyroidSTab = uitab(app.TabGroup2);
            app.MMIThyroidSTab.Title = 'MMI-Thyroid S';
            app.MMI_TS = uiaxes(app.MMIThyroidSTab);
            title(app.MMI_TS, 'Title')
            xlabel(app.MMI_TS, 'X')
            ylabel(app.MMI_TS, 'Y')
            app.MMITRAbTab = uitab(app.TabGroup2);
            app.MMITRAbTab.Title = 'MMI-TRAb';
            app.MMI_TRAB = uiaxes(app.MMITRAbTab);
            title(app.MMI_TRAB, 'Title')
            xlabel(app.MMI_TRAB, 'X')
            ylabel(app.MMI_TRAB, 'Y')
            app.FT4MMITab = uitab(app.TabGroup2);
            app.FT4MMITab.Title = 'FT4-MMI';
            app.FT4_MMI = uiaxes(app.FT4MMITab);
            title(app.FT4_MMI, 'Title')
            xlabel(app.FT4_MMI, 'X')
            ylabel(app.FT4_MMI, 'Y')
            app.FT4ThyroidSTab = uitab(app.TabGroup2);
            app.FT4ThyroidSTab.Title = 'FT4-Thyroid S';
            app.FT4_TS = uiaxes(app.FT4ThyroidSTab);
            title(app.FT4_TS, 'Title')
            xlabel(app.FT4_TS, 'X')
            ylabel(app.FT4_TS, 'Y')
            app.FT4TRAbTab = uitab(app.TabGroup2);
            app.FT4TRAbTab.Title = 'FT4-TRAb';
            app.FT4_TRAB = uiaxes(app.FT4TRAbTab);
            title(app.FT4_TRAB, 'Title')
            xlabel(app.FT4_TRAB, 'X')
            ylabel(app.FT4_TRAB, 'Y')
            app.ThyroidSMMITab = uitab(app.TabGroup2);
            app.ThyroidSMMITab.Title = 'Thyroid S - MMI';
            app.TS_MMI = uiaxes(app.ThyroidSMMITab);
            title(app.TS_MMI, 'Title')
            xlabel(app.TS_MMI, 'X')
            ylabel(app.TS_MMI, 'Y')
            app.ThyroidSFT4Tab = uitab(app.TabGroup2);
            app.ThyroidSFT4Tab.Title = 'Thyroid S - FT4';
            app.TS_FT4 = uiaxes(app.ThyroidSFT4Tab);
            title(app.TS_FT4, 'Title')
            xlabel(app.TS_FT4, 'X')
            ylabel(app.TS_FT4, 'Y')
            app.ThyroidSTRAbTab = uitab(app.TabGroup2);
            app.ThyroidSTRAbTab.Title = 'Thyroid S - TRAb';
            app.TS_TRAB = uiaxes(app.ThyroidSTRAbTab);
            title(app.TS_TRAB, 'Title')
            xlabel(app.TS_TRAB, 'X')
            ylabel(app.TS_TRAB, 'Y')
            app.TRAbMMITab = uitab(app.TabGroup2);
            app.TRAbMMITab.Title = 'TRAb - MMI';
            app.TRAB_MMI = uiaxes(app.TRAbMMITab);
            title(app.TRAB_MMI, 'Title')
            xlabel(app.TRAB_MMI, 'X')
            ylabel(app.TRAB_MMI, 'Y')
            app.TRAbFT4Tab = uitab(app.TabGroup2);
            app.TRAbFT4Tab.Title = 'TRAb-FT4';
            app.TRAB_FT4 = uiaxes(app.TRAbFT4Tab);
            title(app.TRAB_FT4, 'Title')
            xlabel(app.TRAB_FT4, 'X')
            ylabel(app.TRAB_FT4, 'Y')
            app.TRAbThyroidSTab = uitab(app.TabGroup2);
            app.TRAbThyroidSTab.Title = 'TRAb-Thyroid S';
            app.TRAB_TS = uiaxes(app.TRAbThyroidSTab);
            title(app.TRAB_TS, 'Title')
            xlabel(app.TRAB_TS, 'X')
            ylabel(app.TRAB_TS, 'Y')
            app.DetailsTab = uitab(app.TabGroup);
            app.DetailsTab.Title = 'Details';
            app.EigenResultTable = uitable(app.DetailsTab);
            app.EigenResultTable.ColumnName = {'Eigen Values'};
            app.EigenResultTable.RowName = {};
            app.InitialvaluesLabel = uilabel(app.DetailsTab);
            app.InitialvaluesLabel.Text = 'Initial values';
            app.TRAbEditFieldLabel = uilabel(app.DetailsTab);
            app.TRAbEditFieldLabel.Text = 'TRAb';
            app.TRAbEditField = uieditfield(app.DetailsTab, 'numeric');
            app.TRAbEditField.Editable = 'off';
            app.MMIEditFieldLabel = uilabel(app.DetailsTab);
            app.MMIEditFieldLabel.Text = 'MMI';
            app.MMIEditField = uieditfield(app.DetailsTab, 'numeric');
            app.MMIEditField.Editable = 'off';
            app.FT4EditFieldLabel = uilabel(app.DetailsTab);
            app.FT4EditFieldLabel.Text = 'FT4';
            app.FT4EditField = uieditfield(app.DetailsTab, 'numeric');
            app.FT4EditField.Editable = 'off';
            app.ThyroidsizeEditFieldLabel = uilabel(app.DetailsTab);
            app.ThyroidsizeEditFieldLabel.Text = 'Thyroid size';
            app.ThyroidsizeEditField = uieditfield(app.DetailsTab, 'numeric');
            app.ThyroidsizeEditField.Editable = 'off';
            app.SimulationtimeEditFieldLabel = uilabel(app.DetailsTab);
            app.SimulationtimeEditFieldLabel.Text = 'Simulation time';
            app.SimulationtimeEditField = uieditfield(app.DetailsTab, 'numeric');
            app.SimulationtimeEditField.Editable = 'off';
            app.SimulationtimeEditField.Value = 10;
            app.DoseEditFieldLabel = uilabel(app.DetailsTab);
            app.DoseEditFieldLabel.Text = 'Dose';
            app.DoseEditField = uieditfield(app.DetailsTab, 'text');
            app.DoseEditField.Editable = 'off';
            app.DoseEditField.Value = '0';
            app.DaysEditFieldLabel = uilabel(app.DetailsTab);
            app.DaysEditFieldLabel.Text = 'Days';
            app.DaysEditField = uieditfield(app.DetailsTab, 'text');
            app.DaysEditField.Editable = 'off';
            app.DaysEditField.Value = '0';
            app.PrintCopyButton = uibutton(app.UIFigure, 'push');
            app.PrintCopyButton.ButtonPushedFcn = createCallbackFcn(app, @print, true);
            app.PrintCopyButton.Text = 'Print & Copy';
            app.FigureButton = uibutton(app.UIFigure, 'push');
            app.FigureButton.ButtonPushedFcn = createCallbackFcn(app, @figure, true);
            app.FigureButton.Text = 'Figure';
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonFnc, true);
            app.CompareButton.Text = 'Compare';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimulationView

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end