classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ChoosePatientDropDownLabel  matlab.ui.control.Label
        ChoosePatientDropDown       matlab.ui.control.DropDown
        UITable                     matlab.ui.control.Table
        EditparameterLabel          matlab.ui.control.Label
        MMItreatmentLabel           matlab.ui.control.Label
        InitialvaluesLabel          matlab.ui.control.Label
        TreatmentSwitch             matlab.ui.control.Switch
        SimulateButton              matlab.ui.control.Button
        MMIEditFieldLabel           matlab.ui.control.Label
        MMIEditField                matlab.ui.control.NumericEditField
        FT4EditFieldLabel           matlab.ui.control.Label
        FT4EditField                matlab.ui.control.NumericEditField
        ThyroidsizeEditFieldLabel   matlab.ui.control.Label
        ThyroidsizeEditField        matlab.ui.control.NumericEditField
        TRAbEditFieldLabel          matlab.ui.control.Label
        TRAbEditField               matlab.ui.control.NumericEditField
        DoseEditField_2Label        matlab.ui.control.Label
        DoseEditField               matlab.ui.control.EditField
        DaysEditFieldLabel          matlab.ui.control.Label
        DaysEditField               matlab.ui.control.EditField
        InfoButton                  matlab.ui.control.Button
        AdvancedDoseButton          matlab.ui.control.Button
        SimpleDoseButton            matlab.ui.control.Button
        SimulationtimeLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        Data, % Description data to plot
        mapPatient,
        StartData,
        currentPatient,
        CompareData
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            addpath DataGenerator
            addpath Interfaces
            addpath Logic
            addpath View
            savepath
            
            mStartData = IStartData;
            mStartData.CompareData.Figure = figure;
            
            mStartData.CompareData.Figure.Visible = 'off';
            mStartData.CompareData.FunctionArray = [];
            
            mStartData.PatientList = GeneratePatientList();
            
            mStartData.Doses = GenerateDoses();
            
            MMI0 = calculateInitX(0);
            FT40 = mStartData.PatientList(1).FT4;
            TS0 = 30;
            TRAb0 = mStartData.PatientList(1).TRAb;
            mStartData.InitialValues = InitialValues(MMI0, FT40, TS0, TRAb0);
            
            %           init patient
            app.currentPatient = mStartData.PatientList(1);
            %             UITable and ChoosePatientDropDown
            tableData = mStartData.PatientList(1).getTable();
            app.UITable.Data = tableData;
            
            map = containers.Map;
            [a, b] = size(mStartData.PatientList);
            
            patientLabels = string(b);
            for i = 1: b
                newLabel = mStartData.PatientList(i).label;
                map(newLabel) = i;
                patientLabels(i) = newLabel;
            end
            %             Dose
            currentDose = mStartData.Doses(1);

%             todo
%             app.DoseEditField.Value = num2str(currentDose.grams);
%             app.DaysEditField.Value = num2str(currentDose.days);

            app.DoseEditField.Value = '30';
            app.DaysEditField.Value = '10';
            
            %             off treatment
            app.DoseEditField.Enable = false;
%             app.DaysEditField.Enable = false;
%             app.AdvancedDoseButton.Enable = false;
            
            %             initial values
            currentInitialValues = mStartData.InitialValues;
            app.MMIEditField.Value = currentInitialValues.x;
            app.FT4EditField.Value = currentInitialValues.y;
            app.ThyroidsizeEditField.Value = currentInitialValues.z;
            app.TRAbEditField.Value = currentInitialValues.w;
            
            %             export variables
            app.mapPatient = map;
            app.ChoosePatientDropDown.Items = patientLabels;
            app.StartData = mStartData;
            
        end

        % Button pushed function: SimulateButton
        function SimulateBtnClick(app, event)
            %             app.SimulateButton.Text = 'WAIT';
            %             app.ReadyLamp.Label = "In progress";
            
        f = app.UIFigure;
        d = uiprogressdlg(f,'Title','Please Wait',...
        'Message','Calculating', ...
        'Indeterminate','on');
        pause(1)
        
            
%             validate string inputs
            dosesArray = convertStringInputToNArray(app.DoseEditField.Value);
            daysArray = convertStringInputToNArray(app.DaysEditField.Value);
            
            if isnumeric(dosesArray) && isnumeric(daysArray) && ~any(isnan(dosesArray)) && ~any(isnan(daysArray)) && isequal(length(daysArray), length(dosesArray))
                SimulationData = ISimulation;
                SimulationData.Patient = app.currentPatient;
                SimulationData.SimulationTime = sum(daysArray);
                SimulationData.InitialValues = InitialValues(app.MMIEditField.Value, app.FT4EditField.Value, app.ThyroidsizeEditField.Value, app.TRAbEditField.Value);
                
                switch app.TreatmentSwitch.Value
                    case 'On'
                        SimulationData.Dose = Dose(dosesArray, daysArray);
                        
                    case 'Off'
                        SimulationData.Dose.grams = convertStringInputToNArray('0');
                        SimulationData.Dose.days = sum(daysArray);
                end
                
                SimulationResult = ISimulationResult;
                
                SimulationResult = Simulate(SimulationData);
                
                cFunction = CompareFunction;
                cFunction.Data = SimulationResult.X(:,2);
                cFunction.Label = "trials";
                
                array = app.StartData.CompareData.FunctionArray;
                app.StartData.CompareData.FunctionArray = [array cFunction];
                
                SimulationResult.CompareData.FunctionArray = app.StartData.CompareData.FunctionArray;
                SimulationResult.CompareData.T = SimulationResult.T;
                SimulationResult.CompareData.Figure = app.StartData.CompareData.Figure;
                
%                 app.StartData.CompareData = SimulationResult.CompareData;
                SimulationResult.ISimulation = SimulationData;
                
                showApp = SimulationView;
                showApp.ISimulationResult = SimulationResult;
                
                showApp.PlotInit(showApp);
            else
                parsingErrorDialog(app.UIFigure);
            end
         

    % Finish calculations
    % ...
    d.Value = 1;
    d.Message = 'Finishing';
    pause(1)

    % Close dialog box
    close(d);
        end

        % Value changed function: ChoosePatientDropDown
        function ChoosePatientDropDownValueChanged(app, event)
            currentLabel = app.ChoosePatientDropDown.Value;
            pIndex = app.mapPatient(currentLabel);
            
            tableData = app.StartData.PatientList(pIndex).getTable();
            app.UITable.Data = tableData;
            app.FT4EditField.Value = app.StartData.PatientList(pIndex).FT4;
            app.TRAbEditField.Value = app.StartData.PatientList(pIndex).TRAb;
            
            app.currentPatient = app.StartData.PatientList(pIndex);
        end

        % Value changed function: TreatmentSwitch
        function TreatmentSwitchValueChanged(app, event)
            value = app.TreatmentSwitch.Value;
            app.DoseEditField.Enable = value;

            switch value
                case 'On'
                    currentDoses = convertStringInputToNArray(app.DoseEditField.Value);
                    app.MMIEditField.Value = calculateInitX(currentDoses(1));
                case 'Off'
                    app.MMIEditField.Value = calculateInitX(0);
            end
            
%             app.DaysEditField.Enable = value;
        end

        % Button pushed function: InfoButton
        function AdvancedDoseInfo(app, event)
            advancedDoseInfoDialog(app.UIFigure);
        end

        % Button pushed function: AdvancedDoseButton
        function AdvancedDoseButtonPushed(app, event)
            app.DoseEditField.Value = '30, 10, 5, 10';
            app.DaysEditField.Value = '30, 60, 10, 60';
            
            currentDoses = convertStringInputToNArray(app.DoseEditField.Value);
            app.MMIEditField.Value = calculateInitX(currentDoses(1));
        end

        % Button pushed function: SimpleDoseButton
        function SimpleDose(app, event)
            app.DoseEditField.Value = '30';
            app.DaysEditField.Value = '10';
            
            currentDoses = convertStringInputToNArray(app.DoseEditField.Value);
            app.MMIEditField.Value = calculateInitX(currentDoses(1));
        end

        % Value changed function: DoseEditField
        function doseChanged(app, event)
            value = app.DoseEditField.Value;
            currentDoses = convertStringInputToNArray(value);
            
            if isnumeric(currentDoses) && ~any(isnan(currentDoses))
                app.MMIEditField.Value = calculateInitX(currentDoses(1));
            else
                parsingErrorDialog(app.UIFigure);
            end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChoosePatientDropDownLabel = uilabel(app.UIFigure);
            app.ChoosePatientDropDownLabel.Text = 'Choose Patient';
            app.ChoosePatientDropDown = uidropdown(app.UIFigure);
            app.ChoosePatientDropDown.Items = {};
            app.ChoosePatientDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePatientDropDownValueChanged, true);
            app.ChoosePatientDropDown.Value = {};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.EditparameterLabel = uilabel(app.UIFigure);
            app.EditparameterLabel.Text = 'Edit parameter';
            app.MMItreatmentLabel = uilabel(app.UIFigure);
            app.MMItreatmentLabel.Text = 'MMI treatment';
            app.InitialvaluesLabel = uilabel(app.UIFigure);
            app.InitialvaluesLabel.Text = 'Initial values';
            app.TreatmentSwitch = uiswitch(app.UIFigure, 'slider');
            app.TreatmentSwitch.ValueChangedFcn = createCallbackFcn(app, @TreatmentSwitchValueChanged, true);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateBtnClick, true);
            app.SimulateButton.Text = 'Simulate';
            app.MMIEditFieldLabel = uilabel(app.UIFigure);
            app.MMIEditFieldLabel.Text = 'MMI';
            app.MMIEditField = uieditfield(app.UIFigure, 'numeric');
            app.MMIEditField.Editable = 'off';
            app.FT4EditFieldLabel = uilabel(app.UIFigure);
            app.FT4EditFieldLabel.Text = 'FT4';
            app.FT4EditField = uieditfield(app.UIFigure, 'numeric');
            app.FT4EditField.Editable = 'off';
            app.ThyroidsizeEditFieldLabel = uilabel(app.UIFigure);
            app.ThyroidsizeEditFieldLabel.Text = 'Thyroid size';
            app.ThyroidsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThyroidsizeEditField.Editable = 'off';
            app.TRAbEditFieldLabel = uilabel(app.UIFigure);
            app.TRAbEditFieldLabel.Text = 'TRAb';
            app.TRAbEditField = uieditfield(app.UIFigure, 'numeric');
            app.TRAbEditField.Editable = 'off';
            app.DoseEditField_2Label = uilabel(app.UIFigure);
            app.DoseEditField_2Label.Text = 'Dose';
            app.DoseEditField = uieditfield(app.UIFigure, 'text');
            app.DoseEditField.ValueChangedFcn = createCallbackFcn(app, @doseChanged, true);
            app.DaysEditFieldLabel = uilabel(app.UIFigure);
            app.DaysEditFieldLabel.Text = 'Days';
            app.DaysEditField = uieditfield(app.UIFigure, 'text');
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedDoseInfo, true);
            app.InfoButton.Text = 'Info';
            app.AdvancedDoseButton = uibutton(app.UIFigure, 'push');
            app.AdvancedDoseButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedDoseButtonPushed, true);
            app.AdvancedDoseButton.Text = 'Advanced Dose';
            app.SimpleDoseButton = uibutton(app.UIFigure, 'push');
            app.SimpleDoseButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleDose, true);
            app.SimpleDoseButton.Text = 'Simple Dose';
            app.SimulationtimeLabel = uilabel(app.UIFigure);
            app.SimulationtimeLabel.Text = 'Simulation time';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end